classdef ICPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ICProUIFigure           matlab.ui.Figure
        SampletypeMenu          matlab.ui.container.Menu
        SedimentMenu            matlab.ui.container.Menu
        SeawaterbetaMenu        matlab.ui.container.Menu
        BatchMenu               matlab.ui.container.Menu
        LoadBatchMenu           matlab.ui.container.Menu
        SaveBatchMenu           matlab.ui.container.Menu
        DeleteBatchMenu         matlab.ui.container.Menu
        ExportMenu              matlab.ui.container.Menu
        IndResExp               matlab.ui.container.Menu
        TEIndRes                matlab.ui.container.Menu
        IDexport                matlab.ui.container.Menu
        DCexport                matlab.ui.container.Menu
        BCexport                matlab.ui.container.Menu
        OCexport                matlab.ui.container.Menu
        SCexport                matlab.ui.container.Menu
        QCexport                matlab.ui.container.Menu
        RCexport                matlab.ui.container.Menu
        Rawexport               matlab.ui.container.Menu
        IDLRplots               matlab.ui.container.Menu
        ThUIndRes               matlab.ui.container.Menu
        SamRatioExp             matlab.ui.container.Menu
        NatURatioExp            matlab.ui.container.Menu
        ThUIDExp                matlab.ui.container.Menu
        TEsheet                 matlab.ui.container.Menu
        ThUsheet                matlab.ui.container.Menu
        TEThUsheet              matlab.ui.container.Menu
        Panel_TE                matlab.ui.container.Panel
        Button_RawFile          matlab.ui.control.Button
        Field_RawFile           matlab.ui.control.EditField
        Field_SIDTE             matlab.ui.control.EditField
        Field_BlankID           matlab.ui.control.EditField
        Field_SpikeID           matlab.ui.control.EditField
        Label_SIDTE             matlab.ui.control.Label
        Label_BlankID           matlab.ui.control.Label
        Label_SpikeID           matlab.ui.control.Label
        Field_RinseID           matlab.ui.control.EditField
        Label_RinseID           matlab.ui.control.Label
        Field_QCID              matlab.ui.control.EditField
        Label_QCID              matlab.ui.control.Label
        Panel_TECorr            matlab.ui.container.Panel
        Check_RC                matlab.ui.control.CheckBox
        Check_QC                matlab.ui.control.CheckBox
        Check_SC                matlab.ui.control.CheckBox
        Check_OC                matlab.ui.control.CheckBox
        Check_BC                matlab.ui.control.CheckBox
        Check_DC                matlab.ui.control.CheckBox
        Panel_ThU               matlab.ui.container.Panel
        TabGroup2               matlab.ui.container.TabGroup
        InputTab                matlab.ui.container.Tab
        Field_NBlocks           matlab.ui.control.EditField
        Field_NSamples          matlab.ui.control.EditField
        Label_NBlocks           matlab.ui.control.Label
        Label_NSamples          matlab.ui.control.Label
        Field_ICPBlankID        matlab.ui.control.EditField
        Label_ICPBlankID        matlab.ui.control.Label
        Field_ICPNatUID         matlab.ui.control.EditField
        Label_ICPNatUID         matlab.ui.control.Label
        Field_SIDThU            matlab.ui.control.EditField
        Label_SIDThU            matlab.ui.control.Label
        Button_RawFolder        matlab.ui.control.Button
        Field_RawFolder         matlab.ui.control.EditField
        SequenceTab             matlab.ui.container.Tab
        SequenceList            matlab.ui.control.Table
        Panel_IsotopicDilution  matlab.ui.container.Panel
        SpikesPanel             matlab.ui.container.Panel
        UspikeDropDownLabel     matlab.ui.control.Label
        DropDown_236            matlab.ui.control.DropDown
        ThspikeDropDown_2Label  matlab.ui.control.Label
        DropDown_230            matlab.ui.control.DropDown
        ThspikeDropDownLabel    matlab.ui.control.Label
        DropDown_229            matlab.ui.control.DropDown
        Field_RY229             matlab.ui.control.NumericEditField
        Label_RX                matlab.ui.control.Label
        Field_RX229             matlab.ui.control.NumericEditField
        Label_CY                matlab.ui.control.Label
        Field_CY229             matlab.ui.control.NumericEditField
        Field_RY230             matlab.ui.control.NumericEditField
        Field_RY236             matlab.ui.control.NumericEditField
        Field_RX230             matlab.ui.control.NumericEditField
        Field_RX236             matlab.ui.control.NumericEditField
        Field_CY230             matlab.ui.control.NumericEditField
        Field_CY236             matlab.ui.control.NumericEditField
        Label_RY                matlab.ui.control.Label
        Button_EdtSve229        matlab.ui.control.Button
        Button_EdtSve230        matlab.ui.control.Button
        Button_EdtSve236        matlab.ui.control.Button
        Button_Delete229        matlab.ui.control.Button
        Button_Delete230        matlab.ui.control.Button
        Button_Delete236        matlab.ui.control.Button
        Footnote                matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        Tab_IDWeights           matlab.ui.container.Tab
        Field_Sediment          matlab.ui.control.TextArea
        Label_Sediment          matlab.ui.control.Label
        Field_4ml               matlab.ui.control.TextArea
        Field_10ml              matlab.ui.control.TextArea
        Field_400ul             matlab.ui.control.TextArea
        Field_ThBC              matlab.ui.control.TextArea
        Label_4ml               matlab.ui.control.Label
        Label_10ml              matlab.ui.control.Label
        Label_400ul             matlab.ui.control.Label
        Label_ThBC              matlab.ui.control.Label
        Field_UBC               matlab.ui.control.TextArea
        Field_ThSC              matlab.ui.control.TextArea
        Label_UBC               matlab.ui.control.Label
        Label_ThSC              matlab.ui.control.Label
        Field_USC               matlab.ui.control.TextArea
        Label_USC               matlab.ui.control.Label
        Field_SampleList        matlab.ui.control.TextArea
        Label_SampleList        matlab.ui.control.Label
        Button_MakeList         matlab.ui.control.Button
        Field_USC_2             matlab.ui.control.TextArea
        Label_USC_2             matlab.ui.control.Label
        Tab_RawIntensities      matlab.ui.container.Tab
        Field_cpsSampleList     matlab.ui.control.TextArea
        Label_cpsSampleList     matlab.ui.control.Label
        Field_cps230Th          matlab.ui.control.TextArea
        Label_cps230Th          matlab.ui.control.Label
        Field_cps232Th          matlab.ui.control.TextArea
        Field_cps236U           matlab.ui.control.TextArea
        Field_cps238U           matlab.ui.control.TextArea
        Label_cps232Th          matlab.ui.control.Label
        Label_cps236U           matlab.ui.control.Label
        Label_cps238U           matlab.ui.control.Label
        Label_cps230Th_R        matlab.ui.control.Label
        Label_cps232Th_R        matlab.ui.control.Label
        Label_cps236U_R         matlab.ui.control.Label
        Label_cps238U_R         matlab.ui.control.Label
        Field_cps230Th_R        matlab.ui.control.TextArea
        Field_cps232Th_R        matlab.ui.control.TextArea
        Field_cps236U_R         matlab.ui.control.TextArea
        Field_cps238U_R         matlab.ui.control.TextArea
        Footnote2               matlab.ui.control.Label
        Tab_TEresults           matlab.ui.container.Tab
        DataSelTE               matlab.ui.container.ButtonGroup
        RawButton               matlab.ui.control.ToggleButton
        RCButton                matlab.ui.control.ToggleButton
        QCButton                matlab.ui.control.ToggleButton
        SCButton                matlab.ui.control.ToggleButton
        OCButton                matlab.ui.control.ToggleButton
        BCButton                matlab.ui.control.ToggleButton
        DCButton                matlab.ui.control.ToggleButton
        IDButton                matlab.ui.control.ToggleButton
        CUSelTE                 matlab.ui.container.ButtonGroup
        ConcTE                  matlab.ui.control.RadioButton
        ErrTE                   matlab.ui.control.RadioButton
        ErrpTE                  matlab.ui.control.RadioButton
        Table_TEresults         matlab.ui.control.Table
        Tab_TEplot              matlab.ui.container.Tab
        SelectDropDown          matlab.ui.control.DropDown
        UIDFig                  matlab.ui.control.UIAxes
        ThIDFig                 matlab.ui.control.UIAxes
        Tab_ThUresults          matlab.ui.container.Tab
        Table_ThUresults        matlab.ui.control.Table
        DataSelThU              matlab.ui.container.ButtonGroup
        RatioSButton            matlab.ui.control.ToggleButton
        RatioNButton            matlab.ui.control.ToggleButton
        ThUIDButton             matlab.ui.control.ToggleButton
        CUSelThU                matlab.ui.container.ButtonGroup
        ConcThU                 matlab.ui.control.RadioButton
        ErrThU                  matlab.ui.control.RadioButton
        ErrpThU                 matlab.ui.control.RadioButton
        Button_TEtoggle         matlab.ui.control.StateButton
        Button_ThUtoggle        matlab.ui.control.StateButton
        Button_Run              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button_Run, SaveBatchMenu
        function Execute(app, event)
            AppPath = dir([mfilename('fullpath'),'.mlapp']);
            cd([AppPath.folder,'/misc/functions/'])
            %% GET INPUT DATA
            % Trace element parameters
            IN.TEpar.Toggle = app.Button_TEtoggle.Value;
            IN.TEpar.RawPath = app.Field_RawFile.Value;
            IN.TEpar.SID = app.Field_SIDTE.Value;
            IN.TEpar.BID = app.Field_BlankID.Value;
            IN.TEpar.VID = app.Field_SpikeID.Value;
            IN.TEpar.RID = app.Field_RinseID.Value;
            IN.TEpar.QID = app.Field_QCID.Value;
            IN.TEpar.CCheck.RC = app.Check_RC.Value;
            IN.TEpar.CCheck.QC = app.Check_QC.Value;
            IN.TEpar.CCheck.SC = app.Check_SC.Value;
            IN.TEpar.CCheck.OC = app.Check_OC.Value;
            IN.TEpar.CCheck.BC = app.Check_BC.Value;
            IN.TEpar.CCheck.DC = app.Check_DC.Value;
            % Th/U parameters
            IN.ThUpar.Toggle = app.Button_ThUtoggle.Value;
            IN.ThUpar.ThURawPath = app.Field_RawFolder.Value;
            IN.ThUpar.SType = 'Sediment';
            IN.ThUpar.NBlocks = str2double(app.Field_NBlocks.Value);
            IN.ThUpar.NSamples = str2double(app.Field_NSamples.Value);
            IN.ThUpar.SID = app.Field_SIDThU.Value;
            IN.ThUpar.BID = app.Field_ICPBlankID.Value;
            IN.ThUpar.NatUID = app.Field_ICPNatUID.Value;
            % Isotopic dilution parameters (weights, spikes & raw intensities)
            IN.WtPar.m_sed = str2double(app.Field_Sediment.Value)';
            IN.WtPar.m_4ml = str2double(app.Field_4ml.Value)';
            IN.WtPar.m_10ml = str2double(app.Field_10ml.Value)';
            IN.WtPar.m_400ul = str2double(app.Field_400ul.Value)';
            IN.WtPar.m_ThSC = str2double(app.Field_ThSC.Value)';
            IN.WtPar.m_ThBC = str2double(app.Field_ThBC.Value)';
            IN.WtPar.m_USC = str2double(app.Field_USC.Value)';
            IN.WtPar.m_UBC = str2double(app.Field_UBC.Value)';
            IN.SpikePar.Th229RY = app.Field_RY229.Value;
            IN.SpikePar.Th229RX = app.Field_RX229.Value;
            IN.SpikePar.Th229CY = app.Field_CY229.Value;
            IN.SpikePar.Th230RY = app.Field_RY230.Value;
            IN.SpikePar.Th230RX = app.Field_RX230.Value;
            IN.SpikePar.Th230CY = app.Field_CY230.Value;
            IN.SpikePar.U236RY = app.Field_RY236.Value;
            IN.SpikePar.U236RX = app.Field_RX236.Value;
            IN.SpikePar.U236CY = app.Field_CY236.Value;
            IN.cps.Sample = [str2double(app.Field_cps230Th.Value),...
                str2double(app.Field_cps232Th.Value),...
                str2double(app.Field_cps236U.Value),...
                str2double(app.Field_cps238U.Value)]';
            IN.cps.Rinse = [str2double(app.Field_cps230Th_R.Value),...
                str2double(app.Field_cps232Th_R.Value),...
                str2double(app.Field_cps236U_R.Value),...
                str2double(app.Field_cps238U_R.Value)]';
            IN.SpikeSel = [0 0 0];
            if ~isempty(getappdata(app.ICProUIFigure,'Input'))
                getBatch = getappdata(app.ICProUIFigure,'Input');
                IN.BatchName = getBatch.BatchName;
            end
            
            %% DATA PROCESSING
            if event.Source ~= app.SaveBatchMenu
                if app.Button_TEtoggle.Value == 1
                    % Read & process TE raw data
                    [OUT.TE.C,OUT.TE.dC,OUT.TE.Isotopes,OUT.TE.RunID] = TE_ReadRaw(IN.TEpar,1);
                    [OUT.TE.C,OUT.TE.dC,OUT.TE.IsotopesID] = TE_Processor(IN.TEpar,OUT.TE.C,OUT.TE.dC,OUT.TE.Isotopes,OUT.TE.RunID,IN.WtPar,IN.SpikePar,IN.cps);
                    setappdata(app.ICProUIFigure,'Output',OUT)
                    
                    % Display results in table
                    app.Table_TEresults.Data = [OUT.TE.C.DC.f_DC',OUT.TE.C.ID.Sample'];
                    app.Table_TEresults.ColumnName = ['Dilution factor';OUT.TE.IsotopesID];
                    app.Table_TEresults.RowName = OUT.TE.RunID.Sample;
                    app.CUSelTE.SelectedObject = app.ConcTE;
                    setappdata(app.ICProUIFigure,'ActiveResultTE',app.DataSelTE.SelectedObject.Tag);
                    
                    % Plot ID vs linear calibration
                    app.SelectDropDown.Value = 'Th232';
                    % Th229
                    iRTh232 = find(strcmp(OUT.TE.IsotopesID,'Th232(ID)'));
                    ThLR = OUT.TE.C.DC.Sample(iRTh232,1:end);
                    ThID = OUT.TE.C.ID.Sample(iRTh232,1:end);
                    dThLR = OUT.TE.dC.DC.Sample(iRTh232,1:end);
                    dThID = OUT.TE.dC.ID.Sample(iRTh232,1:end);
                    pTh = polyfit(ThLR,ThID,1);
                    fTh = polyval(pTh,ThLR);
                    app.ThIDFig.NextPlot = 'replace';
                    errorbar(app.ThIDFig,ThLR,ThID,dThLR,dThLR,dThID,dThID,'.k','MarkerSize',10,'LineStyle','none');
                    line(app.ThIDFig,ThLR,fTh,'LineStyle','-','Color','k');
                    app.ThIDFig.XLim = [min([app.ThIDFig.XLim(1),app.ThIDFig.YLim(1)]),max([app.ThIDFig.XLim(2),app.ThIDFig.YLim(2)])];
                    app.ThIDFig.YLim = [min([app.ThIDFig.XLim(1),app.ThIDFig.YLim(1)]),max([app.ThIDFig.XLim(2),app.ThIDFig.YLim(2)])];
                    text(app.ThIDFig,app.ThIDFig.XLim(1)+(app.ThIDFig.XLim(2)-app.ThIDFig.XLim(1))*0.1,app.ThIDFig.YLim(1)+(app.ThIDFig.YLim(2)-app.ThIDFig.YLim(1))*0.9,4,['y = ',num2str(pTh(1),'%.2f'),'x + ',num2str(pTh(2),'%.2f')])
                    app.ThIDFig.XGrid = 'on';
                    app.ThIDFig.YGrid = 'on';
                    app.ThIDFig.XLabel.String = '232Th, LR (ppm)';
                    app.ThIDFig.YLabel.String = '232Th, ID (ppm)';
                    % U238
                    iRU238 = find(strcmp(OUT.TE.IsotopesID,'U238(ID)'));
                    ULR = OUT.TE.C.DC.Sample(iRU238,1:end);
                    UID = OUT.TE.C.ID.Sample(iRU238,1:end);
                    dULR = OUT.TE.dC.DC.Sample(iRU238,1:end);
                    dUID = OUT.TE.dC.ID.Sample(iRU238,1:end);
                    pU = polyfit(ULR,UID,1);
                    fU = polyval(pU,ULR);
                    app.UIDFig.NextPlot = 'replace';
                    scatterU = errorbar(app.UIDFig,ULR,UID,dULR,dULR,dUID,dUID,'.k','MarkerSize',10,'LineStyle','none');
                    lineU = line(app.UIDFig,ULR,fU,'LineStyle','-','Color','k');
                    text(app.UIDFig,app.UIDFig.XLim(1)+(app.UIDFig.XLim(2)-app.UIDFig.XLim(1))*0.1,app.UIDFig.YLim(1)+(app.UIDFig.YLim(2)-app.UIDFig.YLim(1))*0.9,4,['y = ',num2str(pU(1),'%.2f'),'x + ',num2str(pU(2),'%.2f')])
                    app.UIDFig.XLim = [min([app.UIDFig.XLim(1),app.UIDFig.YLim(1)]),max([app.UIDFig.XLim(2),app.UIDFig.YLim(2)])];
                    app.UIDFig.YLim = [min([app.UIDFig.XLim(1),app.UIDFig.YLim(1)]),max([app.UIDFig.XLim(2),app.UIDFig.YLim(2)])];
                    app.UIDFig.XGrid = 'on';
                    app.UIDFig.YGrid = 'on';
                    app.UIDFig.XLabel.String = '238U, LR (ppm)';
                    app.UIDFig.YLabel.String = '238U, ID (ppm)';
                    scatterU.Visible = 'off';
                    lineU.Visible = 'off';
                    app.UIDFig.Visible = 'off';
                    
                    % Enable export menu items/result switcher buttons
                    app.IndResExp.Enable = 'on';
                    app.TEIndRes.Enable = 'on';
                    app.IDLRplots.Enable = 'on';
                    app.TEsheet.Enable = app.Check_DC.Value;
                    app.RCexport.Enable = app.Check_RC.Value;
                    app.QCexport.Enable = app.Check_QC.Value;
                    app.SCexport.Enable = app.Check_SC.Value;
                    app.OCexport.Enable = app.Check_OC.Value;
                    app.BCexport.Enable = app.Check_BC.Value;
                    app.DCexport.Enable = app.Check_DC.Value;
                    app.IDexport.Enable = app.Check_DC.Value;
                    app.ErrTE.Enable = 'on';
                    app.ErrpTE.Enable = 'on';
                    app.ConcTE.Enable = 'on';
                    app.RawButton.Enable = 'on';
                    app.RCButton.Enable = app.Check_RC.Value;
                    app.QCButton.Enable = app.Check_QC.Value;
                    app.SCButton.Enable = app.Check_SC.Value;
                    app.OCButton.Enable = app.Check_OC.Value;
                    app.BCButton.Enable = app.Check_BC.Value;
                    app.DCButton.Enable = app.Check_DC.Value;
                    app.IDButton.Enable = app.Check_DC.Value;
                end
                
                if app.Button_ThUtoggle.Value == 1
                    % Process OUT.ThU.Ratios raw data
                    [OUT.ThU.Ratios,OUT.ThU.dRatios,OUT.ThU.SampleSeq,OUT.ThU.NatUSeq,OUT.ThU.Sequence] = ThU_Processor(IN.ThUpar);
                    setappdata(app.ICProUIFigure,'Output',OUT)
                    % Display Sequence
                    SeqTab = cell(ceil(numel(OUT.ThU.Sequence)/3)*3,1);
                    SeqTab(1:numel(OUT.ThU.Sequence)) = OUT.ThU.Sequence;
                    SeqTab = reshape(SeqTab,[],3);
                    app.SequenceList.Data = SeqTab;
                    app.SequenceList.ColumnName = {'Seq. 1/3','Seq. 2/3','Seq 3/3'};
                    % Display results in table
                    app.ThUIDButton.Value = 1;
                    app.ConcThU.Value = 1;
                    app.Table_ThUresults.Data = [OUT.ThU.Ratios.Sample.R229_230_cMB(2:end)',OUT.ThU.Ratios.Sample.R236_234_cMB(2:end)'];
                    app.Table_ThUresults.ColumnName = {'Th229/Th230','U236/U234'};
                    app.Table_ThUresults.RowName = OUT.ThU.SampleSeq(2:end);
                    app.CUSelThU.SelectedObject = app.ConcThU;
                    
                    % Enable export menu items/result switcher buttons
                    app.ThUIndRes.Enable = 'on';
                    app.SamRatioExp.Enable = 'on';
                    app.NatURatioExp.Enable = 'on';
                    app.ThUsheet.Enable = 'on';
                    app.ErrThU.Enable = 'on';
                    app.ErrpThU.Enable = 'on';
                    app.ConcThU.Enable = 'on';
                    app.RatioNButton.Enable = 'on';
                    app.RatioSButton.Enable = 'on';
                end
                
                if app.Button_ThUtoggle.Value == 1 && app.Button_TEtoggle.Value == 1
                    % Th230/U236 Isotopic dilution
                    [OUT.ThU.ID,OUT.ThU.dID] = ThU_ID(IN.SpikePar,IN.WtPar,OUT.ThU.Ratios,OUT.ThU.dRatios);
                    setappdata(app.ICProUIFigure,'Output',OUT)
                    
                    % Display results in table
                    app.ThUIDButton.Value = 1;
                    app.ConcThU.Value = 1;
                    app.Table_ThUresults.Data = [OUT.ThU.Ratios.Sample.R229_230_cMB(2:end)',OUT.ThU.Ratios.Sample.R236_234_cMB(2:end)',OUT.ThU.ID.Th230'*1000,OUT.ThU.ID.U234'*1000];
                    app.Table_ThUresults.ColumnName = {'Th229/Th230','U236/U234'};
                    app.Table_ThUresults.RowName = OUT.ThU.SampleSeq(2:end);
                    app.CUSelThU.SelectedObject = app.ConcThU;
                    setappdata(app.ICProUIFigure,'ActiveResultThU',app.DataSelThU.SelectedObject.Tag);
                    
                    app.TEThUsheet.Enable = app.Check_DC.Value;
                    app.ThUIDButton.Enable = 'on';
                    app.ThUIDExp.Enable = 'on';
                end
                
                if app.Button_ThUtoggle.Value == 0 && app.Button_TEtoggle.Value == 0
                    warndlg('No analyses performed.')
                end
                setappdata(app.ICProUIFigure,'Output',OUT)
            else
                IN.BatchName = inputdlg('Specify batch name:');
                IN.SpikeSel = [app.DropDown_229.Value,app.DropDown_230.Value,app.DropDown_236.Value];
                if ~isempty(IN.BatchName)
                    BatchFiles = dir([AppPath.folder,'/misc/Batch Input Files/*.mat']);
                    for iB = 1 : numel(BatchFiles)
                        BatchExist(iB) =  strcmp([IN.BatchName{1},'.mat'],BatchFiles(iB).name);
                    end
                    if sum(BatchExist) == 1
                        msg = 'Batch file already exists and will be overwritten.';
                        title = 'Overwrite batch file';
                        selection = questdlg(msg,title,'Proceed','Cancel','Proceed');
                        if strcmp(selection,'Proceed')
                            save(['/Users/jweis/MATLAB-Drive/UThProcessor/misc/Batch Input Files/',IN.BatchName{1},'.mat'],'IN');
                        end
                    end
                    save(['/Users/jweis/MATLAB-Drive/UThProcessor/misc/Batch Input Files/',IN.BatchName{1},'.mat'],'IN');
                end
            end
            setappdata(app.ICProUIFigure,'Input',IN)
        end

        % Value changed function: Button_TEtoggle, 
        % Button_ThUtoggle, Check_DC
        function AnalysisToggle(app, event)
            %% Determine analyses' toggle state
            State_TE = app.Button_TEtoggle.Value && app.Check_DC.Value && ~app.Button_ThUtoggle.Value;
            State_ThU = (~app.Button_TEtoggle.Value || app.Button_TEtoggle.Value && ~app.Check_DC.Value) && app.Button_ThUtoggle.Value;
            State_both = app.Button_TEtoggle.Value && app.Check_DC.Value && app.Button_ThUtoggle.Value;
            State_none = (~app.Button_TEtoggle.Value || app.Button_TEtoggle.Value && ~app.Check_DC.Value) && ~app.Button_ThUtoggle.Value;
            
            %% Change toggle button appearance & disable/enable panel
            if event.Source.Value == 1 && event.Source ~= app.Check_DC
                set(event.Source,'Icon','misc/icons/tick.png')
                set(event.Source,'BackgroundColor',[.5 1 .5])
                if event.Source == app.Button_TEtoggle
                    set(app.Panel_TE,'Enable','on')
                elseif event.Source == app.Button_ThUtoggle
                    set(app.Panel_ThU,'Enable','on')
                end
            elseif event.Source.Value == 0 && event.Source ~= app.Check_DC
                set(event.Source,'Icon','misc/icons/x.png')
                set(event.Source,'BackgroundColor',[1 .5 .5])
                if event.Source == app.Button_TEtoggle
                    set(app.Panel_TE,'Enable','off')
                elseif event.Source == app.Button_ThUtoggle
                    set(app.Panel_ThU,'Enable','off')
                end
            end
            
            %% Disable/Enable input fields
            % Get fields for enabling/disabling
            Th229SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','229','-and','-regexp','Type','DropDown','-or','-regexp','Tag','229','-and','-regexp','Type','NumericEditField');
            WeightTabFields = findall(app.Tab_IDWeights,'-regexp','Type','TextArea','-not',{'-regexp','Tag','Field_ThBC'},'-or','-regexp','Type','Button');
            RawIntTabFields = findall(app.Tab_RawIntensities,'-regexp','Type','TextArea','-or','-regexp','Type','Button');
            TEResuTabFields = findall(app.Tab_TEresults,'-regexp','Type','ButtonGroup','-or','-regexp','Type','Table');
            TEPlotTabFields = findall(app.Tab_TEplot,'-regexp','Type','DropDown');
            ThuResTabFields = findall(app.Tab_ThUresults,'-regexp','Type','ButtonGroup','-or','-regexp','Type','Table');
            
            if State_TE
                % DISABLE: ThU results tab & Th229 spike fields
                DisableFields = [ThuResTabFields;Th229SpikeFields;app.Field_ThBC];
                % ENABLE: TE tabs (weights, raw intensity, results, ID plot) & spikes panel
                EnableFields = [WeightTabFields;RawIntTabFields;TEResuTabFields;TEPlotTabFields;app.SpikesPanel];
                % CHANGE TAB: Weights tab (only if ThU results Tab is selected)
                if app.TabGroup.SelectedTab == app.Tab_ThUresults
                    app.TabGroup.SelectedTab = app.Tab_IDWeights;
                end
            elseif State_ThU
                % DISABLE: TE tabs (weights, raw intensity, results, ID plot) & spikes panel
                DisableFields = [WeightTabFields;RawIntTabFields;TEResuTabFields;TEPlotTabFields;app.SpikesPanel];
                % ENABLE: ThU results tab & Th229 spike fields
                EnableFields = [ThuResTabFields;Th229SpikeFields;app.Field_ThBC];
                % CHANGE TAB: ThU results tab (no further input required)
                app.TabGroup.SelectedTab = app.Tab_ThUresults;
            elseif State_both
                % DISABLE: -
                DisableFields = [];
                % ENABLE: TE tabs (weights, raw intensity, results, ID plot), spikes panel & ThU results tab & Th229 spike fields
                EnableFields = [WeightTabFields;RawIntTabFields;TEResuTabFields;TEPlotTabFields;app.SpikesPanel;ThuResTabFields;app.Field_ThBC;Th229SpikeFields];
            elseif State_none
                % DISABLE: TE tabs (weights, raw intensity, results, ID plot), spikes panel & ThU results tab & Th229 spike fields
                DisableFields = [WeightTabFields;RawIntTabFields;TEResuTabFields;TEPlotTabFields;app.SpikesPanel;ThuResTabFields;app.Field_ThBC];
                % ENABLE: -
                EnableFields = Th229SpikeFields;
            end
            
            for iD = 1 : length(DisableFields)
                DisableFields(iD).Enable = 'off';
            end
            for iE = 1 : length(EnableFields)
                EnableFields(iE).Enable = 'on';
            end
            
        end

        % Button pushed function: Button_RawFile, Button_RawFolder
        function BrowseRawData(app, event)
            if event.Source == app.Button_RawFile
                [TERawFile,TERawPath] = uigetfile('.xlsx','Pick a Trace Element raw file');
                if TERawPath ~= 0
                    set(app.Field_RawFile,'Value',[TERawPath,TERawFile])
                end
            elseif event.Source == app.Button_RawFolder
                ThURawPath = uigetdir('','Pick a Th/U raw data folder');
                if ThURawPath ~= 0
                    set(app.Field_RawFolder,'Value',[ThURawPath,'/'])
                end
            end
        end

        % Value changing function: Field_SIDTE
        function AutoFillSampleID(app, event)
            changingValue = event.Value;
            set(app.Field_SIDThU,'Value',changingValue)
        end

        % Callback function: Button_MakeList, Field_RawFile
        function SampleList(app, event)
            if event.Source == app.Button_MakeList || event.Source == app.LoadBatchMenu
                if app.Button_TEtoggle.Value == 1 && app.Check_DC.Value == 1
                    IN.TEpar.RawPath = app.Field_RawFile.Value;
                    IN.TEpar.SID = app.Field_SIDTE.Value;
                    IN.TEpar.BID = app.Field_BlankID.Value;
                    IN.TEpar.VID = app.Field_SpikeID.Value;
                    IN.TEpar.RID = app.Field_RinseID.Value;
                    IN.TEpar.QID = app.Field_QCID.Value;
                    if app.Button_TEtoggle.Value == 1
                        [~,~,~,OUT.TE.RunID] = TE_ReadRaw(IN.TEpar,0);
                    end
                    set(app.Button_MakeList,'Visible','off')
                    set(app.Field_SampleList,'Visible','on')
                    set(app.Field_SampleList,'Value',OUT.TE.RunID.Sample)
                    set(app.Field_cpsSampleList,'Visible','on')
                    set(app.Field_cpsSampleList,'Value',OUT.TE.RunID.Sample)
                elseif app.Button_ThUtoggle.Value == 1 && (app.Check_DC == 0 || app.Button_TEtoggle.Value == 0)
                    FileList = dir([app.Field_RawFolder.Value,'*.txt']);
                    OUT.TE.RunID.all = {FileList.name}';
                    OUT.TE.RunID.Sample = OUT.TE.RunID.all(contains(OUT.TE.RunID.all,app.Field_SIDThU.Value));
                    set(app.Button_MakeList,'Visible','off')
                    set(app.Field_SampleList,'Visible','on')
                    set(app.Field_SampleList,'Value',OUT.TE.RunID.Sample)
                    set(app.Field_cpsSampleList,'Visible','on')
                    set(app.Field_cpsSampleList,'Value',OUT.TE.RunID.Sample)
                end
            elseif event.Source == app.Field_RawFile
                set(app.Button_MakeList,'Visible','on')
                set(app.Field_SampleList,'Visible','off')
            end
        end

        % Callback function: Button_Delete229, Button_Delete230, 
        % Button_Delete236, Button_EdtSve229, Button_EdtSve230, 
        % Button_EdtSve236, DropDown_229, DropDown_229, 
        % DropDown_230, DropDown_230, DropDown_236, DropDown_236
        function Spikes(app, event)
            % Determine spike function to run
            if event.Source ~= app.LoadBatchMenu
                SpikeFun = event.Source.Tag(1:3);
                SpikeIso = event.Source.Tag(4:6);
                if strcmp(SpikeIso,'229')
                    SpikeIsoLong = 'Th229';
                elseif strcmp(SpikeIso,'230')
                    SpikeIsoLong = 'Th230';
                elseif strcmp(SpikeIso,'236')
                    SpikeIsoLong = 'U236';
                end
            else
                SpikeFun = 'load';
                SpikeIso = {'229','230','236'};
                SpikeIsoLong = {'Th229','Th230','U236'};
            end
            
            % Spike dropdown (get/select spikes)
            if strcmp(SpikeFun,'get')
                if strcmp(event.EventName,'DropDownOpening')
                    % Create spike list when dropdown is opened
                    opts = spreadsheetImportOptions('NumVariables',4,'Sheet',SpikeIsoLong,'DataRange','A2','VariableNamesRange','A1');
                    spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
                    set(app.(['DropDown_',SpikeIso]),'Items',['Select';spikes{:,1};'Add'])
                    set(app.(['DropDown_',SpikeIso]),'ItemsData',[0;(1:length(spikes{:,1}))';999])
                elseif strcmp(event.EventName,'ValueChanged')
                    % Change to selected spike
                    value = app.(['DropDown_',SpikeIso]).Value;
                    opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                    spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet',SpikeIsoLong);
                    if value > 0 && value < 999
                        set(app.(['Field_RY',SpikeIso]),'Value',spikes{value,2})
                        set(app.(['Field_RX',SpikeIso]),'Value',spikes{value,3})
                        set(app.(['Field_CY',SpikeIso]),'Value',spikes{value,4})
                        set(app.(['Field_CY',SpikeIso]),'Editable','off')
                        set(app.(['Field_RX',SpikeIso]),'Editable','off')
                        set(app.(['Field_RY',SpikeIso]),'Editable','off')
                        set(app.(['Button_Delete',SpikeIso]),'Enable','on')
                        set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                        set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                        set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
                    elseif value == 0
                        set(app.(['Field_RY',SpikeIso]),'Value',0)
                        set(app.(['Field_RX',SpikeIso]),'Value',0)
                        set(app.(['Field_CY',SpikeIso]),'Value',0)
                        set(app.(['Field_CY',SpikeIso]),'Editable','off')
                        set(app.(['Field_RX',SpikeIso]),'Editable','off')
                        set(app.(['Field_RY',SpikeIso]),'Editable','off')
                        set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                        set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                        set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                        set(app.(['Button_EdtSve',SpikeIso]),'Enable','off')
                    elseif value == 999
                        set(app.(['Field_CY',SpikeIso]),'Editable','on')
                        set(app.(['Field_RX',SpikeIso]),'Editable','on')
                        set(app.(['Field_RY',SpikeIso]),'Editable','on')
                        set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                        set(app.(['Button_EdtSve',SpikeIso]),'Text','save')
                        set(app.(['Button_EdtSve',SpikeIso]),'Tag',['sve',SpikeIso])
                        set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
                        newspikeID = inputdlg('Enter spike ID:');
                        if ~isempty(newspikeID)
                            set(app.(['DropDown_',SpikeIso]),'Items',['Select';spikes{:,1};newspikeID;'Add'])
                            set(app.(['DropDown_',SpikeIso]),'ItemsData',[0;(1:length(spikes{:,1})+1)';999])
                            set(app.(['DropDown_',SpikeIso]),'Value',length(spikes{:,1})+1)
                            set(app.(['Field_RY',SpikeIso]),'Value',0)
                            set(app.(['Field_RX',SpikeIso]),'Value',0)
                            set(app.(['Field_CY',SpikeIso]),'Value',0)
                        else
                            app.(['DropDown_',SpikeIso]).Value = 0;
                            set(app.(['Field_RY',SpikeIso]),'Value',0)
                            set(app.(['Field_RX',SpikeIso]),'Value',0)
                            set(app.(['Field_CY',SpikeIso]),'Value',0)
                            set(app.(['Field_CY',SpikeIso]),'Editable','off')
                            set(app.(['Field_RX',SpikeIso]),'Editable','off')
                            set(app.(['Field_RY',SpikeIso]),'Editable','off')
                            set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                            set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                            set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                            set(app.(['Button_EdtSve',SpikeIso]),'Enable','off')
                        end
                    end
                end
            end
            
            % Edit spikes
            if strcmp(SpikeFun,'edt')
                set(app.(['Field_CY',SpikeIso]),'Editable','on')
                set(app.(['Field_RX',SpikeIso]),'Editable','on')
                set(app.(['Field_RY',SpikeIso]),'Editable','on')
                set(app.(['Button_EdtSve',SpikeIso]),'Text','save')
                set(app.(['Button_EdtSve',SpikeIso]),'Tag',['sve',SpikeIso])
                set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
            end
            
            % Save spikes
            if strcmp(SpikeFun,'sve')
                value = app.(['DropDown_',SpikeIso]).Value;
                opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet',SpikeIsoLong);
                if value > length(spikes{:,1})
                    spikes = [spikes;{0,0,0,0}];
                    spikes{end,1} = app.(['DropDown_',SpikeIso]).Items(app.(['DropDown_',SpikeIso]).Value+1);
                end
                spikes{value,2} = app.(['Field_RY',SpikeIso]).Value;
                spikes{value,3} = app.(['Field_RX',SpikeIso]).Value;
                spikes{value,4} = app.(['Field_CY',SpikeIso]).Value;
                writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet',SpikeIsoLong,'WriteMode','overwritesheet')
                set(app.(['Field_CY',SpikeIso]),'Editable','off')
                set(app.(['Field_RX',SpikeIso]),'Editable','off')
                set(app.(['Field_RY',SpikeIso]),'Editable','off')
                set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                set(app.(['Button_EdtSve',SpikeIso]),'Enable','on')
                set(app.(['Button_Delete',SpikeIso]),'Enable','on')
            end
            
            % Delete spikes
            if strcmp(SpikeFun,'dlt')
                value = app.(['DropDown_',SpikeIso]).Value;
                % Confirm dialog
                msg = ['Do you want to delete spike ''',app.(['DropDown_',SpikeIso]).Items{value+1},''''];
                title = 'Confirm Delete';
                selection = questdlg(msg,title,'Ok','Cancel','Ok');
                if strcmp(selection,'Ok')
                    opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                    spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet',SpikeIsoLong);
                    spikes(value,:) = [];
                    writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet',SpikeIsoLong,'WriteMode','overwritesheet')
                    set(app.(['DropDown_',SpikeIso]),'Items',['Select';spikes{:,1};'Add'])
                    set(app.(['DropDown_',SpikeIso]),'ItemsData',[0;(1:length(spikes{:,1}))';999])
                    app.(['DropDown_',SpikeIso]).Value = 0;
                    set(app.(['Field_RY',SpikeIso]),'Value',0)
                    set(app.(['Field_RX',SpikeIso]),'Value',0)
                    set(app.(['Field_CY',SpikeIso]),'Value',0)
                    set(app.(['Field_CY',SpikeIso]),'Editable','off')
                    set(app.(['Field_RX',SpikeIso]),'Editable','off')
                    set(app.(['Field_RY',SpikeIso]),'Editable','off')
                    set(app.(['Button_EdtSve',SpikeIso]),'Text','edit')
                    set(app.(['Button_EdtSve',SpikeIso]),'Tag',['edt',SpikeIso])
                    set(app.(['Button_EdtSve',SpikeIso]),'Enable','off')
                    set(app.(['Button_Delete',SpikeIso]),'Enable','off')
                end
            end
            
            % Load spikes of imported batch
            if strcmp(SpikeFun,'load')
                IN = getappdata(app.ICProUIFigure,'Input');
                % Create spike list
                for i = 1 : 3
                    opts = spreadsheetImportOptions('NumVariables',4,'Sheet',SpikeIsoLong{i},'DataRange','A2','VariableNamesRange','A1');
                    spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
                    set(app.(['DropDown_',SpikeIso{i}]),'Items',['Select';spikes{:,1};'Add'])
                    set(app.(['DropDown_',SpikeIso{i}]),'ItemsData',[0;(1:length(spikes{:,1}))';999])
                    if isfield(IN,'SpikeSel')
                        set(app.(['DropDown_',SpikeIso{i}]),'Value',IN.SpikeSel(i))
                        opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                        spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet',SpikeIsoLong{i});
                        if IN.SpikeSel(i) > 0 && IN.SpikeSel(i) < 999
                            set(app.(['Field_RY',SpikeIso{i}]),'Value',spikes{IN.SpikeSel(i),2})
                            set(app.(['Field_RX',SpikeIso{i}]),'Value',spikes{IN.SpikeSel(i),3})
                            set(app.(['Field_CY',SpikeIso{i}]),'Value',spikes{IN.SpikeSel(i),4})
                            set(app.(['Field_CY',SpikeIso{i}]),'Editable','off')
                            set(app.(['Field_RX',SpikeIso{i}]),'Editable','off')
                            set(app.(['Field_RY',SpikeIso{i}]),'Editable','off')
                            set(app.(['Button_Delete',SpikeIso{i}]),'Enable','on')
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Text','edit')
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Tag',['edt',SpikeIso{i}])
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','on')
                        elseif IN.SpikeSel(i) == 0
                            set(app.(['Field_RY',SpikeIso{i}]),'Value',0)
                            set(app.(['Field_RX',SpikeIso{i}]),'Value',0)
                            set(app.(['Field_CY',SpikeIso{i}]),'Value',0)
                            set(app.(['Field_CY',SpikeIso{i}]),'Editable','off')
                            set(app.(['Field_RX',SpikeIso{i}]),'Editable','off')
                            set(app.(['Field_RY',SpikeIso{i}]),'Editable','off')
                            set(app.(['Button_Delete',SpikeIso{i}]),'Enable','off')
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Text','edit')
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Tag',['edt',SpikeIso{i}])
                            set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','off')
                        end
                    else
                        set(app.(['DropDown_',SpikeIso{i}]),'Value',0)
                        set(app.(['Field_RY',SpikeIso{i}]),'Value',0)
                        set(app.(['Field_RX',SpikeIso{i}]),'Value',0)
                        set(app.(['Field_CY',SpikeIso{i}]),'Value',0)
                        set(app.(['Field_CY',SpikeIso{i}]),'Editable','off')
                        set(app.(['Field_RX',SpikeIso{i}]),'Editable','off')
                        set(app.(['Field_RY',SpikeIso{i}]),'Editable','off')
                        set(app.(['Button_Delete',SpikeIso{i}]),'Enable','off')
                        set(app.(['Button_EdtSve',SpikeIso{i}]),'Text','edit')
                        set(app.(['Button_EdtSve',SpikeIso{i}]),'Tag',['edt',SpikeIso{i}])
                        set(app.(['Button_EdtSve',SpikeIso{i}]),'Enable','off')
                    end
                end
            end
            
        end

        % Value changed function: SelectDropDown
        function ChangePlot(app, event)
            if strcmp(app.SelectDropDown.Value, 'Th232')
                app.ThIDFig.Visible = 'on';
                app.UIDFig.Visible = 'off';
                for iC = 1 : length(app.ThIDFig.Children)
                    app.ThIDFig.Children(iC).Visible = 'on';
                end
                for iC = 1 : length(app.UIDFig.Children)
                    app.UIDFig.Children(iC).Visible = 'off';
                end
            elseif strcmp(app.SelectDropDown.Value, 'U238')
                app.ThIDFig.Visible = 'off';
                app.UIDFig.Visible = 'on';
                for iC = 1 : length(app.ThIDFig.Children)
                    app.ThIDFig.Children(iC).Visible = 'off';
                end
                for iC = 1 : length(app.UIDFig.Children)
                    app.UIDFig.Children(iC).Visible = 'on';
                end
            end
        end

        % Selection changed function: CUSelTE, DataSelTE
        function SwitchResultTE(app, event)
            Selection = app.DataSelTE.SelectedObject.Tag;
            OUT = getappdata(app.ICProUIFigure,'Output');
            if strcmp(app.CUSelTE.SelectedObject.Tag,'Conc')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [OUT.TE.C.DC.f_DC',OUT.TE.C.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = OUT.TE.C.(Selection).Sample';
                end
            elseif strcmp(app.CUSelTE.SelectedObject.Tag,'Err')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [OUT.TE.dC.DC.f_DC',OUT.TE.dC.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = OUT.TE.dC.(Selection).Sample';
                end
            elseif strcmp(app.CUSelTE.SelectedObject.Tag,'Errp')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [OUT.TE.dC.DC.f_DC'./OUT.TE.C.DC.f_DC',OUT.TE.dC.(Selection).Sample'./OUT.TE.C.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = OUT.TE.dC.(Selection).Sample'./OUT.TE.C.(Selection).Sample';
                end
            end
            setappdata(app.ICProUIFigure,'ActiveResultTE',Selection);
            
            if (strcmp(Selection,'ID')) && app.Check_DC.Value == 1
                OUT.TE.IsotopesID = getappdata(app.ICProUIFigure,'OUT_TE_IsotopesID');
                app.Table_TEresults.ColumnName = ['Dilution factor';OUT.TE.IsotopesID];
            elseif (strcmp(Selection,'ID')) && app.Check_DC.Value == 0
                OUT.TE.IsotopesID = getappdata(app.ICProUIFigure,'OUT_TE_IsotopesID');
                app.Table_TEresults.ColumnName = OUT.TE.IsotopesID;
            elseif strcmp(Selection,'DC')
                OUT.TE.Isotopes = getappdata(app.ICProUIFigure,'OUT_TE_Isotopes');
                app.Table_TEresults.ColumnName = ['Dilution factor';OUT.TE.Isotopes];
            else
                OUT.TE.Isotopes = getappdata(app.ICProUIFigure,'OUT_TE_Isotopes');
                app.Table_TEresults.ColumnName = OUT.TE.Isotopes;
            end
        end

        % Selection changed function: CUSelThU, DataSelThU
        function SwitchResultThU(app, event)
            Selection = app.DataSelThU.SelectedObject.Tag;
            OUT = getappdata(app.ICProUIFigure,'Output');
            if strcmp(app.CUSelThU.SelectedObject.Tag,'Conc')
                if strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [OUT.ThU.Ratios.Sample.R229_230_cMB(2:end)',OUT.ThU.Ratios.Sample.R236_234_cMB(2:end)'];
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = OUT.ThU.Ratios.NatU.R235_234_cMB(2:end);
                elseif strcmp(Selection,'ThUID')
                    app.Table_ThUresults.Data = [OUT.ThU.ID.Th230',OUT.ThU.ID.U234'];
                end
            elseif strcmp(app.CUSelThU.SelectedObject.Tag,'Err')
                if strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [OUT.ThU.dRatios.Sample.R229_230_cMB(2:end)',OUT.ThU.dRatios.Sample.R236_234_cMB(2:end)'];
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = OUT.ThU.dRatios.NatU.R235_234_cMB(2:end);
                elseif strcmp(Selection,'ThUID')
                    app.Table_ThUresults.Data = [OUT.ThU.ID.Th230',OUT.ThU.ID.U234'];
                end
            elseif strcmp(app.CUSelThU.SelectedObject.Tag,'Errp')
                if strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [OUT.ThU.dRatios.Sample.R229_230_cMB(2:end)'./OUT.ThU.Ratios.Sample.R229_230_cMB(2:end)',OUT.ThU.dRatios.Sample.R236_234_cMB(2:end)'./OUT.ThU.Ratios.Sample.R236_234_cMB(2:end)']*100;
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = OUT.ThU.dRatios.NatU.R235_234_cMB(2:end)./OUT.ThU.Ratios.NatU.R235_234_cMB(2:end)*100;
                elseif strcmp(Selection,'ThUID')
                    app.Table_ThUresults.Data = [OUT.ThU.dID.Th230'./OUT.ThU.ID.Th230',OUT.ThU.dID.U234'./OUT.ThU.ID.U234']*100;
                end
            end
            
            if strcmp(Selection,'SampleMB')
                app.Table_ThUresults.ColumnName = {'Th229/Th230','U236/U234'};
                app.Table_ThUresults.RowName = OUT.ThU.SampleSeq(2:end);
                app.ConcThU.Text = '  Ratios';
                app.ErrThU.Text = '  Errors';
            elseif strcmp(Selection,'NatUMB')
                app.Table_ThUresults.ColumnName = {'U235/U234'};
                app.Table_ThUresults.RowName = OUT.ThU.NatUSeq;
                app.ConcThU.Text = '  Ratios';
                app.ErrThU.Text = '  Errors';
            elseif strcmp(Selection,'ThUID')
                app.Table_ThUresults.ColumnName = {'Th230 (ID)','U234 (ID)'};
                app.Table_ThUresults.RowName = OUT.ThU.SampleSeq(2:end);
                app.ConcThU.Text = '  Conc. (ppm)';
                app.ErrThU.Text = '  Errors (ppm)';
            end
            setappdata(app.ICProUIFigure,'ActiveResultThU',Selection);
        end

        % Selection change function: TabGroup
        function DisableTabs(app, event)
            if 1 == 0
            selectedTab = app.TabGroup.SelectedTab;
            if app.Button_TEtoggle.Value == 0 || (app.Button_TEtoggle.Value == 1 && app.Check_DC.Value == 0)
                if selectedTab == app.Tab_IDWeights || selectedTab == app.Tab_RawIntensities || selectedTab == app.Tab_TEresults || selectedTab == app.Tab_TEplot
                    app.TabGroup.SelectedTab = event.OldValue;
                end
            elseif app.Button_ThUtoggle.Value == 0
                if selectedTab == app.Tab_ThUresults
                    app.TabGroup.SelectedTab = event.OldValue;
                end
            end
            end
        end

        % Menu selected function: BCexport, DCexport, IDLRplots, 
        % IDexport, NatURatioExp, OCexport, QCexport, RCexport, 
        % Rawexport, SCexport, SamRatioExp, TEThUsheet, TEsheet, 
        % ThUIDExp, ThUsheet
        function Export(app, event)
            OUT = getappdata(app.ICProUIFigure,'Output');
            IN = getappdata(app.ICProUIFigure,'Input');
            
            if isfield(IN,'BatchName')
                FileName = IN.BatchName;
            else
                %                 Execute(app,event)
                %                 FileName = IN.BatchName;
                FileName = '[EnterBatchName]';
            end
            [FilePath1,FilePath2] = uiputfile('*.*','Select File',FileName);
            
            if ~isfolder([FilePath2,'/ICProResults/'])
                mkdir([FilePath2,'/ICProResults/'])
            end
            
            OutFile = [FilePath2,'/ICProResults/',FilePath1,'.xlsx'];
            OutFig = [FilePath2,'/ICProResults/',FilePath1,'.png'];
            if isfile(OutFile)
                delete(OutFile)
            end
            if isfile(OutFig)
                delete(OutFig)
            end
            
            % Info sheet [TBD]
            
            
            % Weight input sheet
            OutTableWt = array2table([IN.WtPar.m_sed;IN.WtPar.m_4ml;IN.WtPar.m_10ml;IN.WtPar.m_400ul;IN.WtPar.m_ThBC;IN.WtPar.m_UBC;IN.WtPar.m_ThSC;IN.WtPar.m_USC]',...
                'RowNames',OUT.TE.RunID.Sample,'VariableNames',{'Sediment wt.','4ml wt.','10ml wt.','400ul wt.','Th229 (BC) wt.','U236 (BC) wt.','Th230 (SC) wt.','U236 (SC) wt.'});
            OutTableWt.Properties.DimensionNames{1} = 'ID';
            writetable(OutTableWt,OutFile,'Sheet','Sample weights in mg','WriteMode','overwritesheet','WriteRowNames',true)
            
            % TE result sheets, concentration & errors (individual)
            if event.Source.Parent == app.TEIndRes || event.Source == app.TEsheet || event.Source == app.TEThUsheet
                gettag = event.Source.Tag;
                if strcmp(gettag,'ID') || event.Source == app.TEsheet || event.Source == app.TEThUsheet
                    OutTableC = array2table(OUT.TE.C.ID.Sample','RowNames',OUT.TE.RunID.Sample,'VariableNames',OUT.TE.IsotopesID);
                    OutTableE = array2table(OUT.TE.dC.ID.Sample','RowNames',OUT.TE.RunID.Sample,'VariableNames',OUT.TE.IsotopesID);
                    OutTableC.Properties.DimensionNames{1} = 'ID';
                    OutTableE.Properties.DimensionNames{1} = 'ID';
                    writetable(OutTableC,OutFile,'Sheet','{TE} Concentrations (ID) in ppm','WriteMode','overwritesheet','WriteRowNames',true)
                    writetable(OutTableE,OutFile,'Sheet','{TE} Errors (ID) in ppm','WriteMode','overwritesheet','WriteRowNames',true)
                else
                    OutTableC = array2table(OUT.TE.C.(gettag).Sample','RowNames',OUT.TE.RunID.Sample,'VariableNames',OUT.TE.Isotopes);
                    OutTableE = array2table(OUT.TE.dC.(gettag).Sample','RowNames',OUT.TE.RunID.Sample,'VariableNames',OUT.TE.Isotopes);
                    OutTableC.Properties.DimensionNames{1} = 'ID';
                    OutTableE.Properties.DimensionNames{1} = 'ID';
                    writetable(OutTableC,OutFile,'Sheet',['{TE} Concentrations (',gettag,') in ppm'],'WriteMode','overwritesheet','WriteRowNames',true)
                    writetable(OutTableE,OutFile,'Sheet',['{TE} Errors (',gettag,') in ppm'],'WriteMode','overwritesheet','WriteRowNames',true)
                end
            end
            
            % ID vs LR plots
            if event.Source == app.IDLRplots || event.Source == app.TEsheet || event.Source == app.TEThUsheet
                fig = figure('Visible','off');
                fig.Position = [0 0 1000 500];
                h1 = copyobj(app.ThIDFig,fig);
                set(h1,'ActivePositionProperty','outerposition')
                set(h1,'Units','normalized')
                set(h1,'OuterPosition',[0 0 1 1])
                set(h1,'Position',[0.025 0.05 .45 .9])
                set(h1,'Visible','on')
                for iC = 1 : length(h1.Children)
                    h1.Children(iC).Visible = 'on';
                end
                h2 = copyobj(app.UIDFig,fig);
                set(h2,'ActivePositionProperty','outerposition')
                set(h2,'Units','normalized')
                set(h2,'OuterPosition',[0 0 1 1])
                set(h2,'Position',[0.525 0.05 .45 .9])
                set(h2,'Visible','on')
                for iC = 1 : length(h2.Children)
                    h2.Children(iC).Visible = 'on';
                end
                saveas(fig,OutFig)
                close(fig)
            end
            
            % Th/U result sheets, concentration & errors (individual)
            if event.Source.Parent == app.ThUIndRes || event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                if event.Source == app.SamRatioExp || event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                    OutTableCa = array2table([OUT.ThU.Ratios.Sample.R229_230_cMB(2:end)',OUT.ThU.Ratios.Sample.R236_234_cMB(2:end)'],'RowNames',OUT.ThU.SampleSeq(2:end),'VariableNames',{'Th229/Th230','U236/U234'});
                    OutTableEa = array2table([OUT.ThU.dRatios.Sample.R229_230_cMB(2:end)',OUT.ThU.dRatios.Sample.R236_234_cMB(2:end)'],'RowNames',OUT.ThU.SampleSeq(2:end),'VariableNames',{'Th229/Th230','U236/U234'});
                    OutTableCa.Properties.DimensionNames{1} = 'ID';
                    OutTableEa.Properties.DimensionNames{1} = 'ID';
                end
                if event.Source == app.NatURatioExp || event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                    OutTableCb = array2table(OUT.ThU.Ratios.NatU.R235_234_cMB,'RowNames',OUT.ThU.NatUSeq,'VariableNames',{'U235/U234'});
                    OutTableEb = array2table(OUT.ThU.dRatios.NatU.R235_234_cMB,'RowNames',OUT.ThU.NatUSeq,'VariableNames',{'U235/U234'});
                    OutTableCb.Properties.DimensionNames{1} = 'ID';
                    OutTableEb.Properties.DimensionNames{1} = 'ID';
                end
                if event.Source == app.ThUIDExp || event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                    OutTableCc = array2table([OUT.ThU.ID.Th230',OUT.ThU.ID.U234'],'RowNames',OUT.ThU.SampleSeq(2:end),'VariableNames',{'Th230 (ID)','U234 (ID)'});
                    % OutTableEc = array2table([OUT.ThU.dID.Th230',OUT.ThU.dID.U234'],'RowNames',OUT.ThU.SampleSeq(2:end),'VariableNames',{'Th230 (ID)','U234 (ID)'});
                    OutTableEc = array2table([OUT.ThU.ID.Th230',OUT.ThU.ID.U234']*NaN,'RowNames',OUT.ThU.SampleSeq(2:end),'VariableNames',{'Th230 (ID)','U234 (ID)'});
                    OutTableCc.Properties.DimensionNames{1} = 'ID';
                    OutTableEc.Properties.DimensionNames{1} = 'ID';
                end
                if event.Source == app.ThUsheet || event.Source == app.TEThUsheet
                    writetable(OutTableCa,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','inplace','WriteRowNames',true,'Range','A1')
                    writetable(OutTableCb,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','inplace','WriteRowNames',true,'Range','G1')
                    writetable(OutTableCc,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','inplace','WriteRowNames',false,'Range','D1')
                    writetable(OutTableEa,OutFile,'Sheet','{Th & U} Errors','WriteMode','inplace','WriteRowNames',true,'Range','A1')
                    writetable(OutTableEb,OutFile,'Sheet','{Th & U} Errors','WriteMode','inplace','WriteRowNames',true,'Range','G1')
                    writetable(OutTableEc,OutFile,'Sheet','{Th & U} Errors','WriteMode','inplace','WriteRowNames',false,'Range','D1')
                elseif event.Source == app.SamRatioExp
                    writetable(OutTableCa,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                    writetable(OutTableEa,OutFile,'Sheet','{Th & U} Errors','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                elseif event.Source == app.NatURatioExp
                    writetable(OutTableCb,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                    writetable(OutTableEb,OutFile,'Sheet','{Th & U} Errors','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                elseif event.Source == app.ThUIDExp
                    writetable(OutTableCc,OutFile,'Sheet','{Th & U} Ratios & Concentr.','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                    writetable(OutTableEc,OutFile,'Sheet','{Th & U} Errors','WriteMode','overwritesheet','WriteRowNames',true,'Range','A1')
                end
            end
        end

        % Menu selected function: DeleteBatchMenu, LoadBatchMenu
        function LoadBatch(app, event)
            AppPath = dir([mfilename('fullpath'),'.mlapp']);
            BatchFile = uigetfile([AppPath.folder,'/misc/Batch Input Files/*.mat']);
            if event.Source == app.LoadBatchMenu
                %                 OUT = getappdata(app.ICProUIFigure,'Output');
                load(['/Users/jweis/MATLAB-Drive/UThProcessor/misc/Batch Input Files/',BatchFile],'IN')
                IN.BatchName = BatchFile(1:end-4);
                setappdata(app.ICProUIFigure,'Input',IN);
                % Import input data: TE parameters
                app.Button_TEtoggle.Value = IN.TEpar.Toggle;
                app.Field_RawFile.Value = IN.TEpar.RawPath;
                app.Field_SIDTE.Value = IN.TEpar.SID;
                app.Field_BlankID.Value = IN.TEpar.BID;
                app.Field_SpikeID.Value = IN.TEpar.VID;
                app.Field_RinseID.Value = IN.TEpar.RID;
                app.Field_QCID.Value = IN.TEpar.QID;
                app.Check_RC.Value = IN.TEpar.CCheck.RC;
                app.Check_QC.Value = IN.TEpar.CCheck.QC;
                app.Check_SC.Value = IN.TEpar.CCheck.SC;
                app.Check_OC.Value = IN.TEpar.CCheck.OC;
                app.Check_BC.Value = IN.TEpar.CCheck.BC;
                app.Check_DC.Value = IN.TEpar.CCheck.DC;
                % Import input data: Th/U parameters
                app.Button_ThUtoggle.Value = IN.ThUpar.Toggle;
                app.Field_RawFolder.Value = IN.ThUpar.ThURawPath;
                app.Field_NBlocks.Value = num2str(IN.ThUpar.NBlocks);
                app.Field_NSamples.Value = num2str(IN.ThUpar.NSamples);
                app.Field_SIDThU.Value = IN.ThUpar.SID;
                app.Field_ICPBlankID.Value = IN.ThUpar.BID;
                app.Field_ICPNatUID.Value = IN.ThUpar.NatUID;
                % Import input data:  Isotopic dilution parameters
                SampleList(app,event)
                app.Field_Sediment.Value = cellstr(num2str(IN.WtPar.m_sed'));
                app.Field_4ml.Value = cellstr(num2str(IN.WtPar.m_4ml'));
                app.Field_10ml.Value = cellstr(num2str(IN.WtPar.m_10ml'));
                app.Field_400ul.Value = cellstr(num2str(IN.WtPar.m_400ul'));
                app.Field_ThSC.Value = cellstr(num2str(IN.WtPar.m_ThSC'));
                app.Field_ThBC.Value = cellstr(num2str(IN.WtPar.m_ThBC'));
                app.Field_USC.Value = cellstr(num2str(IN.WtPar.m_USC'));
                app.Field_UBC.Value = cellstr(num2str(IN.WtPar.m_UBC'));
                app.Field_cps230Th.Value = cellstr(num2str(IN.cps.Sample(1,:)'));
                app.Field_cps232Th.Value = cellstr(num2str(IN.cps.Sample(2,:)'));
                app.Field_cps236U.Value = cellstr(num2str(IN.cps.Sample(3,:)'));
                app.Field_cps238U.Value = cellstr(num2str(IN.cps.Sample(4,:)'));
                app.Field_cps230Th_R.Value = cellstr(num2str(IN.cps.Rinse(1,:)'));
                app.Field_cps232Th_R.Value = cellstr(num2str(IN.cps.Rinse(2,:)'));
                app.Field_cps236U_R.Value = cellstr(num2str(IN.cps.Rinse(3,:)'));
                app.Field_cps238U_R.Value = cellstr(num2str(IN.cps.Rinse(4,:)'));
                
                Spikes(app,event)
            else
                if BatchFile ~= 0
                    msg = ['Do you want to delete batch input file ''',BatchFile(1:end-4),'''?'];
                    title = 'Confirm';
                    selection = questdlg(msg,title,'Proceed','Cancel','Proceed');
                    if strcmp(selection,'Proceed')
                        delete(['/Users/jweis/MATLAB-Drive/UThProcessor/misc/Batch Input Files/',BatchFile]);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ICProUIFigure = uifigure('Visible', 'off');
            app.ICProUIFigure.Name = 'ICPro';
            app.SampletypeMenu = uimenu(app.ICProUIFigure);
            app.SampletypeMenu.Text = 'Sample type';
            app.SedimentMenu = uimenu(app.SampletypeMenu);
            app.SedimentMenu.Accelerator = '1';
            app.SedimentMenu.Checked = 'on';
            app.SedimentMenu.Text = 'Sediment';
            app.SeawaterbetaMenu = uimenu(app.SampletypeMenu);
            app.SeawaterbetaMenu.Accelerator = '2';
            app.SeawaterbetaMenu.Text = 'Sea water (beta)';
            app.BatchMenu = uimenu(app.ICProUIFigure);
            app.BatchMenu.Text = 'Batch';
            app.LoadBatchMenu = uimenu(app.BatchMenu);
            app.LoadBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadBatch, true);
            app.LoadBatchMenu.Text = 'Load';
            app.SaveBatchMenu = uimenu(app.BatchMenu);
            app.SaveBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @Execute, true);
            app.SaveBatchMenu.Text = 'Save';
            app.DeleteBatchMenu = uimenu(app.BatchMenu);
            app.DeleteBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadBatch, true);
            app.DeleteBatchMenu.Text = 'Delete';
            app.ExportMenu = uimenu(app.ICProUIFigure);
            app.ExportMenu.Tooltip = {'Export options'};
            app.ExportMenu.Text = 'Export';
            app.IndResExp = uimenu(app.ExportMenu);
            app.IndResExp.Tooltip = {''};
            app.IndResExp.Enable = 'off';
            app.IndResExp.Text = 'Individual results';
            app.TEIndRes = uimenu(app.IndResExp);
            app.TEIndRes.Tooltip = {'Exports concentrations and uncertainties'};
            app.TEIndRes.Enable = 'off';
            app.TEIndRes.Text = 'Trace elements';
            app.IDexport = uimenu(app.TEIndRes);
            app.IDexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.IDexport.Enable = 'off';
            app.IDexport.Accelerator = 'E';
            app.IDexport.Text = 'Isotopic dilution';
            app.IDexport.Tag = 'ID';
            app.DCexport = uimenu(app.TEIndRes);
            app.DCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.DCexport.Enable = 'off';
            app.DCexport.Accelerator = 'R';
            app.DCexport.Text = 'Dilution corrected';
            app.DCexport.Tag = 'DC';
            app.BCexport = uimenu(app.TEIndRes);
            app.BCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.BCexport.Enable = 'off';
            app.BCexport.Accelerator = 'T';
            app.BCexport.Text = 'Blank corrected';
            app.BCexport.Tag = 'BC';
            app.OCexport = uimenu(app.TEIndRes);
            app.OCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.OCexport.Enable = 'off';
            app.OCexport.Accelerator = 'Y';
            app.OCexport.Text = 'Oxide corrected';
            app.OCexport.Tag = 'OC';
            app.SCexport = uimenu(app.TEIndRes);
            app.SCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.SCexport.Enable = 'off';
            app.SCexport.Accelerator = 'U';
            app.SCexport.Text = 'Spike corrected';
            app.SCexport.Tag = 'SC';
            app.QCexport = uimenu(app.TEIndRes);
            app.QCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.QCexport.Enable = 'off';
            app.QCexport.Accelerator = 'I';
            app.QCexport.Text = 'QC corrected';
            app.QCexport.Tag = 'QC';
            app.RCexport = uimenu(app.TEIndRes);
            app.RCexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.RCexport.Enable = 'off';
            app.RCexport.Accelerator = 'O';
            app.RCexport.Text = 'Rinse corrected';
            app.RCexport.Tag = 'RC';
            app.Rawexport = uimenu(app.TEIndRes);
            app.Rawexport.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.Rawexport.Accelerator = 'P';
            app.Rawexport.Text = 'Raw data';
            app.Rawexport.Tag = 'Raw';
            app.IDLRplots = uimenu(app.IndResExp);
            app.IDLRplots.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.IDLRplots.Tooltip = {'Exports ID vs LR plots (Th232 and U238)'};
            app.IDLRplots.Enable = 'off';
            app.IDLRplots.Accelerator = 'L';
            app.IDLRplots.Text = 'ID vs LR plots';
            app.ThUIndRes = uimenu(app.IndResExp);
            app.ThUIndRes.Tooltip = {'Exports ratios and uncertainties'};
            app.ThUIndRes.Enable = 'off';
            app.ThUIndRes.Text = 'Th/U';
            app.SamRatioExp = uimenu(app.ThUIndRes);
            app.SamRatioExp.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.SamRatioExp.Tooltip = {'Th230/Th229'; 'U236/U234'};
            app.SamRatioExp.Enable = 'off';
            app.SamRatioExp.Accelerator = 'D';
            app.SamRatioExp.Text = 'Sample ratios';
            app.NatURatioExp = uimenu(app.ThUIndRes);
            app.NatURatioExp.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.NatURatioExp.Tooltip = {'U235/U234'};
            app.NatURatioExp.Enable = 'off';
            app.NatURatioExp.Accelerator = 'F';
            app.NatURatioExp.Text = 'NatU ratio';
            app.ThUIDExp = uimenu(app.ThUIndRes);
            app.ThUIDExp.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.ThUIDExp.Tooltip = {'Th230 & U234'};
            app.ThUIDExp.Enable = 'off';
            app.ThUIDExp.Accelerator = 'G';
            app.ThUIDExp.Text = 'Isotopic Dilution';
            app.TEsheet = uimenu(app.ExportMenu);
            app.TEsheet.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.TEsheet.Tooltip = {'- TE concentrations and uncertainties (with isotopic dilution)'; '- ID vs LR plots (Th232, U238)'};
            app.TEsheet.Enable = 'off';
            app.TEsheet.Accelerator = '3';
            app.TEsheet.Text = 'TE result sheet';
            app.ThUsheet = uimenu(app.ExportMenu);
            app.ThUsheet.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.ThUsheet.Tooltip = {'- Mass bias corrected sample ratios and uncertainties (Th229/Th230, U236/U234)'; '- Mass bias corrected NatU ratio and uncertainties (U236/U234)'};
            app.ThUsheet.Enable = 'off';
            app.ThUsheet.Accelerator = '4';
            app.ThUsheet.Text = 'Th/U result sheet';
            app.TEThUsheet = uimenu(app.ExportMenu);
            app.TEThUsheet.MenuSelectedFcn = createCallbackFcn(app, @Export, true);
            app.TEThUsheet.Tooltip = {'- TE concentrations and uncertainties (with isotopic dilution)'; '- ID vs LR plots (Th232, U238)'; '- Mass bias corrected sample ratios and uncertainties (Th229/Th230, U236/U234)'; '- Mass bias corrected NatU ratio and uncertainties (U236/U234)'};
            app.TEThUsheet.Enable = 'off';
            app.TEThUsheet.Accelerator = '5';
            app.TEThUsheet.Text = 'TE+Th/U result sheet';
            app.Panel_TE = uipanel(app.ICProUIFigure);
            app.Panel_TE.Enable = 'off';
            app.Panel_TE.Title = 'Trace element data processing';
            app.Button_RawFile = uibutton(app.Panel_TE, 'push');
            app.Button_RawFile.ButtonPushedFcn = createCallbackFcn(app, @BrowseRawData, true);
            app.Button_RawFile.Text = '...';
            app.Field_RawFile = uieditfield(app.Panel_TE, 'text');
            app.Field_RawFile.ValueChangedFcn = createCallbackFcn(app, @SampleList, true);
            app.Field_SIDTE = uieditfield(app.Panel_TE, 'text');
            app.Field_SIDTE.ValueChangingFcn = createCallbackFcn(app, @AutoFillSampleID, true);
            app.Field_BlankID = uieditfield(app.Panel_TE, 'text');
            app.Field_SpikeID = uieditfield(app.Panel_TE, 'text');
            app.Field_SpikeID.Value = 'spike';
            app.Label_SIDTE = uilabel(app.Panel_TE);
            app.Label_SIDTE.Text = 'Sample ID initials';
            app.Label_BlankID = uilabel(app.Panel_TE);
            app.Label_BlankID.Text = 'Procedural blank ID';
            app.Label_SpikeID = uilabel(app.Panel_TE);
            app.Label_SpikeID.Text = 'Spiked sample ID';
            app.Field_RinseID = uieditfield(app.Panel_TE, 'text');
            app.Field_RinseID.Value = 'Rinse';
            app.Label_RinseID = uilabel(app.Panel_TE);
            app.Label_RinseID.Text = 'Rinse ID';
            app.Field_QCID = uieditfield(app.Panel_TE, 'text');
            app.Field_QCID.Value = 'QC';
            app.Label_QCID = uilabel(app.Panel_TE);
            app.Label_QCID.Text = 'QC ID';
            app.Panel_TECorr = uipanel(app.Panel_TE);
            app.Panel_TECorr.Title = 'Select corrections to apply';
            app.Check_RC = uicheckbox(app.Panel_TECorr);
            app.Check_RC.Tag = 'RC';
            app.Check_RC.Text = 'Rinse correction';
            app.Check_QC = uicheckbox(app.Panel_TECorr);
            app.Check_QC.Tag = 'QC';
            app.Check_QC.Text = 'QC correction';
            app.Check_QC.Value = true;
            app.Check_SC = uicheckbox(app.Panel_TECorr);
            app.Check_SC.Tag = 'SC';
            app.Check_SC.Text = 'Spike correction';
            app.Check_OC = uicheckbox(app.Panel_TECorr);
            app.Check_OC.Tag = 'OC';
            app.Check_OC.Text = 'Oxide correction';
            app.Check_BC = uicheckbox(app.Panel_TECorr);
            app.Check_BC.Tag = 'BC';
            app.Check_BC.Text = 'Blank correction';
            app.Check_BC.Value = true;
            app.Check_DC = uicheckbox(app.Panel_TECorr);
            app.Check_DC.ValueChangedFcn = createCallbackFcn(app, @AnalysisToggle, true);
            app.Check_DC.Tag = 'DC';
            app.Check_DC.Text = 'Dilution correction';
            app.Check_DC.Value = true;
            app.Panel_ThU = uipanel(app.ICProUIFigure);
            app.Panel_ThU.Enable = 'off';
            app.Panel_ThU.Title = 'Th/U data processing';
            app.TabGroup2 = uitabgroup(app.Panel_ThU);
            app.InputTab = uitab(app.TabGroup2);
            app.InputTab.Title = 'Input';
            app.Field_NBlocks = uieditfield(app.InputTab, 'text');
            app.Field_NSamples = uieditfield(app.InputTab, 'text');
            app.Label_NBlocks = uilabel(app.InputTab);
            app.Label_NBlocks.Text = 'No. of analysis blocks';
            app.Label_NSamples = uilabel(app.InputTab);
            app.Label_NSamples.Text = 'No. of samples per block';
            app.Field_ICPBlankID = uieditfield(app.InputTab, 'text');
            app.Field_ICPBlankID.Value = 'Blank';
            app.Label_ICPBlankID = uilabel(app.InputTab);
            app.Label_ICPBlankID.Text = 'ICP Blank ID';
            app.Field_ICPNatUID = uieditfield(app.InputTab, 'text');
            app.Field_ICPNatUID.Value = 'NatU';
            app.Label_ICPNatUID = uilabel(app.InputTab);
            app.Label_ICPNatUID.Text = 'ICP NatU ID';
            app.Field_SIDThU = uieditfield(app.InputTab, 'text');
            app.Label_SIDThU = uilabel(app.InputTab);
            app.Label_SIDThU.Text = 'Sample ID initials';
            app.Button_RawFolder = uibutton(app.InputTab, 'push');
            app.Button_RawFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseRawData, true);
            app.Button_RawFolder.Text = '...';
            app.Field_RawFolder = uieditfield(app.InputTab, 'text');
            app.SequenceTab = uitab(app.TabGroup2);
            app.SequenceTab.Title = 'Sequence';
            app.SequenceList = uitable(app.SequenceTab);
            app.SequenceList.ColumnName = '';
            app.SequenceList.RowName = {};
            app.Panel_IsotopicDilution = uipanel(app.ICProUIFigure);
            app.Panel_IsotopicDilution.Title = 'Isotopic Dilution Input & Results';
            app.SpikesPanel = uipanel(app.Panel_IsotopicDilution);
            app.SpikesPanel.Title = 'Spikes';
            app.UspikeDropDownLabel = uilabel(app.SpikesPanel);
            app.UspikeDropDownLabel.Text = '236U spike';
            app.DropDown_236 = uidropdown(app.SpikesPanel);
            app.DropDown_236.Items = {'Select'};
            app.DropDown_236.ItemsData = 0;
            app.DropDown_236.DropDownOpeningFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_236.ValueChangedFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_236.Tag = 'get236';
            app.DropDown_236.Value = 0;
            app.ThspikeDropDown_2Label = uilabel(app.SpikesPanel);
            app.ThspikeDropDown_2Label.Text = '230Th spike';
            app.DropDown_230 = uidropdown(app.SpikesPanel);
            app.DropDown_230.Items = {'Select'};
            app.DropDown_230.ItemsData = 0;
            app.DropDown_230.DropDownOpeningFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_230.ValueChangedFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_230.Tag = 'get230';
            app.DropDown_230.Value = 0;
            app.ThspikeDropDownLabel = uilabel(app.SpikesPanel);
            app.ThspikeDropDownLabel.Text = '229Th spike';
            app.DropDown_229 = uidropdown(app.SpikesPanel);
            app.DropDown_229.Items = {'Select'};
            app.DropDown_229.ItemsData = 0;
            app.DropDown_229.DropDownOpeningFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_229.ValueChangedFcn = createCallbackFcn(app, @Spikes, true);
            app.DropDown_229.Tag = 'get229';
            app.DropDown_229.Value = 0;
            app.Field_RY229 = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_RY229.Tag = 'Field_229ThRY';
            app.Field_RY229.Editable = 'off';
            app.Label_RX = uilabel(app.SpikesPanel);
            app.Label_RX.Tooltip = {'Isotope amount ratio of sample'};
            app.Label_RX.Text = 'RX';
            app.Field_RX229 = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_RX229.Tag = 'Field_229ThRX';
            app.Field_RX229.Editable = 'off';
            app.Label_CY = uilabel(app.SpikesPanel);
            app.Label_CY.Tooltip = {'spike concentration (pg/g)'};
            app.Label_CY.Text = 'CY';
            app.Field_CY229 = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_CY229.Tag = 'Field_229ThCY';
            app.Field_CY229.Editable = 'off';
            app.Field_RY230 = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_RY230.Editable = 'off';
            app.Field_RY236 = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_RY236.Editable = 'off';
            app.Field_RX230 = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_RX230.Editable = 'off';
            app.Field_RX236 = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_RX236.Editable = 'off';
            app.Field_CY230 = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_CY230.Editable = 'off';
            app.Field_CY236 = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_CY236.Editable = 'off';
            app.Label_RY = uilabel(app.SpikesPanel);
            app.Label_RY.Tooltip = {'Isotope amount ratio of spike'};
            app.Label_RY.Text = 'RY';
            app.Button_EdtSve229 = uibutton(app.SpikesPanel, 'push');
            app.Button_EdtSve229.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_EdtSve229.Tag = 'edt229';
            app.Button_EdtSve229.Enable = 'off';
            app.Button_EdtSve229.Text = 'edit';
            app.Button_EdtSve230 = uibutton(app.SpikesPanel, 'push');
            app.Button_EdtSve230.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_EdtSve230.Tag = 'edt230';
            app.Button_EdtSve230.Enable = 'off';
            app.Button_EdtSve230.Text = 'edit';
            app.Button_EdtSve236 = uibutton(app.SpikesPanel, 'push');
            app.Button_EdtSve236.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_EdtSve236.Tag = 'edt236';
            app.Button_EdtSve236.Enable = 'off';
            app.Button_EdtSve236.Text = 'edit';
            app.Button_Delete229 = uibutton(app.SpikesPanel, 'push');
            app.Button_Delete229.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_Delete229.Tag = 'dlt229';
            app.Button_Delete229.Enable = 'off';
            app.Button_Delete229.Text = 'remove';
            app.Button_Delete230 = uibutton(app.SpikesPanel, 'push');
            app.Button_Delete230.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_Delete230.Tag = 'dlt230';
            app.Button_Delete230.Enable = 'off';
            app.Button_Delete230.Text = 'remove';
            app.Button_Delete236 = uibutton(app.SpikesPanel, 'push');
            app.Button_Delete236.ButtonPushedFcn = createCallbackFcn(app, @Spikes, true);
            app.Button_Delete236.Tag = 'dlt236';
            app.Button_Delete236.Enable = 'off';
            app.Button_Delete236.Text = 'remove';
            app.Footnote = uilabel(app.SpikesPanel);
            app.Footnote.Tooltip = {''};
            app.Footnote.Text = 'RY: isotope amount ratio of spike, RX: isotope amount ratio of sample, CY: spike concentration (pg/g)';
            app.TabGroup = uitabgroup(app.Panel_IsotopicDilution);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @DisableTabs, true);
            app.Tab_IDWeights = uitab(app.TabGroup);
            app.Tab_IDWeights.Title = 'Weights [mg]';
            app.Field_Sediment = uitextarea(app.Tab_IDWeights);
            app.Field_Sediment.Tag = 'Field_Sediment';
            app.Label_Sediment = uilabel(app.Tab_IDWeights);
            app.Label_Sediment.Text = 'Sediment';
            app.Field_4ml = uitextarea(app.Tab_IDWeights);
            app.Field_4ml.Tag = 'Field_4ml';
            app.Field_10ml = uitextarea(app.Tab_IDWeights);
            app.Field_10ml.Tag = 'Field_10ml';
            app.Field_400ul = uitextarea(app.Tab_IDWeights);
            app.Field_400ul.Tag = 'Field_400ul';
            app.Field_ThBC = uitextarea(app.Tab_IDWeights);
            app.Field_ThBC.Tag = 'Field_ThBC';
            app.Label_4ml = uilabel(app.Tab_IDWeights);
            app.Label_4ml.Text = '4 ml';
            app.Label_10ml = uilabel(app.Tab_IDWeights);
            app.Label_10ml.Text = '10 ml';
            app.Label_400ul = uilabel(app.Tab_IDWeights);
            app.Label_400ul.Text = '400 ul';
            app.Label_ThBC = uilabel(app.Tab_IDWeights);
            app.Label_ThBC.Text = '229Th (BC)';
            app.Field_UBC = uitextarea(app.Tab_IDWeights);
            app.Field_UBC.Tag = 'Field_UBC';
            app.Field_ThSC = uitextarea(app.Tab_IDWeights);
            app.Field_ThSC.Tag = 'Field_ThSC';
            app.Label_UBC = uilabel(app.Tab_IDWeights);
            app.Label_UBC.Text = '236U (BC)';
            app.Label_ThSC = uilabel(app.Tab_IDWeights);
            app.Label_ThSC.Text = '230Th (SC)';
            app.Field_USC = uitextarea(app.Tab_IDWeights);
            app.Field_USC.Tag = 'Field_USC';
            app.Label_USC = uilabel(app.Tab_IDWeights);
            app.Label_USC.Text = '236U (SC)';
            app.Field_SampleList = uitextarea(app.Tab_IDWeights);
            app.Field_SampleList.Tag = 'Field_SampleList';
            app.Field_SampleList.Editable = 'off';
            app.Field_SampleList.Visible = 'off';
            app.Label_SampleList = uilabel(app.Tab_IDWeights);
            app.Label_SampleList.Text = 'ID';
            app.Button_MakeList = uibutton(app.Tab_IDWeights, 'push');
            app.Button_MakeList.ButtonPushedFcn = createCallbackFcn(app, @SampleList, true);
            app.Button_MakeList.Text = 'Generate';
            app.Field_USC_2 = uitextarea(app.Tab_IDWeights);
            app.Field_USC_2.Tag = 'Field_USC';
            app.Field_USC_2.Visible = 'off';
            app.Field_USC_2.Value = {'154.6'; '154.2'; '153.1'; '154.9'; '153.1'; '153.8'; '154.7'; '153.5'; '153.5'; '154.4'; '154.6'; '153.0'; '153.8'; '153.0'; '152.9'; '155.1'; '154.9'; '155.0'; '154.5'; '154.6'; '154.0'; '154.6'; '154.8'};
            app.Label_USC_2 = uilabel(app.Tab_IDWeights);
            app.Label_USC_2.Visible = 'off';
            app.Label_USC_2.Text = 'Age [kyr]';
            app.Tab_RawIntensities = uitab(app.TabGroup);
            app.Tab_RawIntensities.Title = 'Th & U raw intensities [cps]';
            app.Field_cpsSampleList = uitextarea(app.Tab_RawIntensities);
            app.Field_cpsSampleList.Editable = 'off';
            app.Field_cpsSampleList.Visible = 'off';
            app.Label_cpsSampleList = uilabel(app.Tab_RawIntensities);
            app.Label_cpsSampleList.Text = 'ID';
            app.Field_cps230Th = uitextarea(app.Tab_RawIntensities);
            app.Label_cps230Th = uilabel(app.Tab_RawIntensities);
            app.Label_cps230Th.Text = 'Th230';
            app.Field_cps232Th = uitextarea(app.Tab_RawIntensities);
            app.Field_cps236U = uitextarea(app.Tab_RawIntensities);
            app.Field_cps238U = uitextarea(app.Tab_RawIntensities);
            app.Label_cps232Th = uilabel(app.Tab_RawIntensities);
            app.Label_cps232Th.Text = '232Th';
            app.Label_cps236U = uilabel(app.Tab_RawIntensities);
            app.Label_cps236U.Text = '236U';
            app.Label_cps238U = uilabel(app.Tab_RawIntensities);
            app.Label_cps238U.Text = '238U';
            app.Label_cps230Th_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps230Th_R.Text = 'Th230 Rinse';
            app.Label_cps232Th_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps232Th_R.Text = '232Th Rinse';
            app.Label_cps236U_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps236U_R.Text = '236U Rinse';
            app.Label_cps238U_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps238U_R.Text = '238U Rinse';
            app.Field_cps230Th_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps230Th_R.Value = {'optional'};
            app.Field_cps232Th_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps232Th_R.Value = {'optional'};
            app.Field_cps236U_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps236U_R.Value = {'optional'};
            app.Field_cps238U_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps238U_R.Value = {'optional'};
            app.Footnote2 = uilabel(app.Tab_RawIntensities);
            app.Footnote2.Text = 'Rinse raw intensities are required for rinse correction. If not specified, Th and U raw intensities will not be rinse corrected.';
            app.Tab_TEresults = uitab(app.TabGroup);
            app.Tab_TEresults.Title = 'Results (TE)';
            app.DataSelTE = uibuttongroup(app.Tab_TEresults);
            app.DataSelTE.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultTE, true);
            app.RawButton = uitogglebutton(app.DataSelTE);
            app.RawButton.Tag = 'Raw';
            app.RawButton.Enable = 'off';
            app.RawButton.Text = 'Raw data';
            app.RCButton = uitogglebutton(app.DataSelTE);
            app.RCButton.Tag = 'RC';
            app.RCButton.Enable = 'off';
            app.RCButton.Text = 'Rinse corrected';
            app.QCButton = uitogglebutton(app.DataSelTE);
            app.QCButton.Tag = 'QC';
            app.QCButton.Enable = 'off';
            app.QCButton.Text = 'QC corrected';
            app.SCButton = uitogglebutton(app.DataSelTE);
            app.SCButton.Tag = 'SC';
            app.SCButton.Enable = 'off';
            app.SCButton.Text = 'Spike corrected';
            app.OCButton = uitogglebutton(app.DataSelTE);
            app.OCButton.Tag = 'OC';
            app.OCButton.Enable = 'off';
            app.OCButton.Text = 'Oxide corrected';
            app.BCButton = uitogglebutton(app.DataSelTE);
            app.BCButton.Tag = 'BC';
            app.BCButton.Enable = 'off';
            app.BCButton.Text = 'Blank corrected';
            app.DCButton = uitogglebutton(app.DataSelTE);
            app.DCButton.Tag = 'DC';
            app.DCButton.Enable = 'off';
            app.DCButton.Text = 'Dilution corrected';
            app.IDButton = uitogglebutton(app.DataSelTE);
            app.IDButton.Tag = 'ID';
            app.IDButton.Enable = 'off';
            app.IDButton.Tooltip = {'Th232 and U238 concentrations determined via isotopic dilution'};
            app.IDButton.Text = 'Isotopic Dilution';
            app.IDButton.Value = true;
            app.CUSelTE = uibuttongroup(app.DataSelTE);
            app.CUSelTE.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultTE, true);
            app.ConcTE = uiradiobutton(app.CUSelTE);
            app.ConcTE.Tag = 'Conc';
            app.ConcTE.Enable = 'off';
            app.ConcTE.Text = '  Conc. (ppm)';
            app.ConcTE.Value = true;
            app.ErrTE = uiradiobutton(app.CUSelTE);
            app.ErrTE.Tag = 'Err';
            app.ErrTE.Enable = 'off';
            app.ErrTE.Text = '  Errors (ppm)';
            app.ErrpTE = uiradiobutton(app.CUSelTE);
            app.ErrpTE.Tag = 'Errp';
            app.ErrpTE.Enable = 'off';
            app.ErrpTE.Text = '  Errors (%)';
            app.Table_TEresults = uitable(app.Tab_TEresults);
            app.Table_TEresults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_TEresults.RowName = {};
            app.Tab_TEplot = uitab(app.TabGroup);
            app.Tab_TEplot.Title = 'ID vs LR (TE)';
            app.SelectDropDown = uidropdown(app.Tab_TEplot);
            app.SelectDropDown.Items = {'Th232', 'U238'};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.SelectDropDown.Value = 'Th232';
            app.UIDFig = uiaxes(app.Tab_TEplot);
            xlabel(app.UIDFig, 'Linear calibration (ppm)')
            ylabel(app.UIDFig, 'Isotopic dilution (ppm)')
            zlabel(app.UIDFig, 'Z')
            app.UIDFig.Visible = 'off';
            app.ThIDFig = uiaxes(app.Tab_TEplot);
            xlabel(app.ThIDFig, 'Linear calibration (ppm)')
            ylabel(app.ThIDFig, 'Isotopic dilution (ppm)')
            zlabel(app.ThIDFig, 'Z')
            app.Tab_ThUresults = uitab(app.TabGroup);
            app.Tab_ThUresults.Title = 'Results (Th & U)';
            app.Table_ThUresults = uitable(app.Tab_ThUresults);
            app.Table_ThUresults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_ThUresults.RowName = {};
            app.DataSelThU = uibuttongroup(app.Tab_ThUresults);
            app.DataSelThU.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultThU, true);
            app.RatioSButton = uitogglebutton(app.DataSelThU);
            app.RatioSButton.Tag = 'SampleMB';
            app.RatioSButton.Enable = 'off';
            app.RatioSButton.Text = 'Sample ratios';
            app.RatioSButton.Value = true;
            app.RatioNButton = uitogglebutton(app.DataSelThU);
            app.RatioNButton.Tag = 'NatUMB';
            app.RatioNButton.Enable = 'off';
            app.RatioNButton.Text = 'NatU ratio';
            app.ThUIDButton = uitogglebutton(app.DataSelThU);
            app.ThUIDButton.Tag = 'ThUID';
            app.ThUIDButton.Enable = 'off';
            app.ThUIDButton.Text = 'Th230 & U234 ID';
            app.CUSelThU = uibuttongroup(app.Tab_ThUresults);
            app.CUSelThU.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultThU, true);
            app.ConcThU = uiradiobutton(app.CUSelThU);
            app.ConcThU.Tag = 'Conc';
            app.ConcThU.Enable = 'off';
            app.ConcThU.Text = '  Conc. (ppm)';
            app.ConcThU.Value = true;
            app.ErrThU = uiradiobutton(app.CUSelThU);
            app.ErrThU.Tag = 'Err';
            app.ErrThU.Enable = 'off';
            app.ErrThU.Text = '  Errors (ppm)';
            app.ErrpThU = uiradiobutton(app.CUSelThU);
            app.ErrpThU.Tag = 'Errp';
            app.ErrpThU.Enable = 'off';
            app.ErrpThU.Text = '  Errors (%)';
            app.Button_TEtoggle = uibutton(app.ICProUIFigure, 'state');
            app.Button_TEtoggle.ValueChangedFcn = createCallbackFcn(app, @AnalysisToggle, true);
            app.Button_TEtoggle.Tooltip = {''};
            app.Button_TEtoggle.Icon = 'x.png';
            app.Button_TEtoggle.Text = '';
            app.Button_ThUtoggle = uibutton(app.ICProUIFigure, 'state');
            app.Button_ThUtoggle.ValueChangedFcn = createCallbackFcn(app, @AnalysisToggle, true);
            app.Button_ThUtoggle.Tooltip = {''};
            app.Button_ThUtoggle.Icon = 'x.png';
            app.Button_ThUtoggle.Text = '';
            app.Button_Run = uibutton(app.ICProUIFigure, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Execute, true);
            app.Button_Run.Icon = 'go.png';
            app.Button_Run.Text = '';
            % Show the figure after all components are created
            app.ICProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ICProUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ICProUIFigure)
        end
    end
end